classdef lccripper < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LCCRipperUIFigure          matlab.ui.Figure
        SlicemethodButtonGroup     matlab.ui.container.ButtonGroup
        ValueEditField             matlab.ui.control.NumericEditField
        ValueEditFieldLabel        matlab.ui.control.Label
        SpanlengthButton           matlab.ui.control.RadioButton
        NumberofsectionsButton     matlab.ui.control.RadioButton
        LinelengthmEditField       matlab.ui.control.NumericEditField
        LinelengthmEditFieldLabel  matlab.ui.control.Label
        SlicendiceButton           matlab.ui.control.Button
        SourcefileatpEditField     matlab.ui.control.TextArea
        SourcefilexlmLabel         matlab.ui.control.Label
        LoadFileButton             matlab.ui.control.Button
    end


    methods (Access = private)

        function [out] = uiquestdlg(app,msg,title,opts,defaultopt,cancelopt)
            h_fig = findall(0,'Name',app.LCCRipperUIFigure.Name);
            out=uiconfirm(h_fig,msg,title,'Options',opts,'DefaultOption',defaultopt,'CancelOption',cancelopt);
        end

        function [out] = uimsgbox(app,msg,title,icon)
            h_fig = findall(0,'Name',app.LCCRipperUIFigure.Name);
            out = h_fig;
            uialert(h_fig,msg,title,'Icon',icon);
        end

        function [] = func_fix_text_labels(app,inputFilePath, outputFilePath)
            % Read the input file
            fid = fopen(inputFilePath, 'r');
            content = fread(fid, '*char')';
            fclose(fid);

            % Regular expression pattern to match <text_content> tags with their inner <Text> tags
            pattern = '<text_content\s+[^>]*>\s*<Text>(.*?)</Text>\s*</text_content>';

            % Find matches using regular expression
            matches = regexp(content, pattern, 'tokens');

            % Loop through matches and replace <text_content> tags with modified content
            for i = 1:length(matches)
                match = matches{i};
                replacement = match{1};  % Content within <Text> and </Text> tags
                content = strrep(content, sprintf('<Text>%s</Text>', replacement), replacement);
            end

            % Write the modified content to the output file
            fid = fopen(outputFilePath, 'w');
            fwrite(fid, content);
            fclose(fid);
        end


        function [] = func_fix_var_tags(app,inputFilePath, outputFilePath)
            % Read the input file
            fid = fopen(inputFilePath, 'r');
            content = fread(fid, '*char')';
            fclose(fid);

            % Regular expression pattern to match <text_content> tags with their inner <Text> tags
            pattern = '<variables\s+[^>]*>\s*<VarStr>(.*?)</VarStr>\s*</variables>';

            % Find matches using regular expression
            matches = regexp(content, pattern, 'tokens');

            % Loop through matches and replace <text_content> tags with modified content
            for i = 1:length(matches)
                match = matches{i};
                replacement = match{1};  % Content within <Text> and </Text> tags
                content = strrep(content, sprintf('<VarStr>%s</VarStr>', replacement), replacement);
            end

            % Write the modified content to the output file
            fid = fopen(outputFilePath, 'w');
            fwrite(fid, content);
            fclose(fid);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            [file,path] = uigetfile({'*.xml'});
            % app.ATPBatlab.Visible = 'off';
            % app.ATPBatlab.Visible = 'on';
            if file ~=0
                fname=fullfile(path,file);
                app.SourcefileatpEditField.Value=fname;
            end
        end

        % Button pushed function: SlicendiceButton
        function SlicendiceButtonPushed(app, event)

            % Wrap your delicate XML reading in a try-catch to handle inevitable failure
            try
                % Attempt to read the struct from the file
                myfile = string(app.SourcefileatpEditField.Value);
                linelen=app.LinelengthmEditField.Value;
                spanlen=app.SpanlengthButton.Value;
                numsec=app.NumberofsectionsButton.Value;
                param=app.ValueEditField.Value;
                LCCData = readstruct(myfile, 'FileType', 'xml');
                disp('Congrats, you managed to read the XML file without screwing up. Amazing!');

                % Check if 'ApplicationAttribute' exists and equals 'ATPDraw'
                if isfield(LCCData, 'ApplicationAttribute') && strcmp(LCCData.ApplicationAttribute, 'ATPDraw')
                    disp('Wow, the ApplicationAttribute is set correctly. Who would have thought?');
                else
                    error('The XML ApplicationAttribute is a complete disaster. The XML must saved from ''ATPDraw'' and not by using a smart toaster or something.');
                end

                % Check if LCCData.objects.comp.LCC exists and is a 1x1 struct
                if isfield(LCCData, 'objects') && ...
                        isfield(LCCData.objects, 'comp') && ...
                        isfield(LCCData.objects.comp, 'LCC') && ...
                        isstruct(LCCData.objects.comp.LCC) && ...
                        numel(LCCData.objects.comp.LCC) == 1
                    disp('Incredible! The LCC object exists and is a 1x1 struct. Against all odds, you did it.');
                else
                    error('LCCData.objects.comp.LCC is a mess. Expected a 1x1 struct but found a trainwreck instead. Need I explain that if you don''t provide at least one LCC object, then I have nothing to do here, and if you provide more than one LCC object, since I don''t have clairvoyance powers, I am unable to guess which is the line you want to slice?');
                end

                % Check if linelen is greater than 0
                if linelen <= 0
                    error('Total line length must be greater than 0. Did you really think zero or negative lengths make sense?');
                end

                % If spanlen is true, validate and divide linelen into N sections of length param
                if spanlen
                    if param > linelen
                        error('Section length cannot be greater than the total line length. Nice try, but physics do apply here.');
                    end

                    % Calculate the number of full sections and the remainder
                    N = floor(linelen / param);
                    section_length=param;
                    remainder = mod(linelen, param);

                    if remainder > 0
                        fprintf('You will have %d sections of length %f and a remainder of %f.\n', N, param, remainder);
                    else
                        fprintf('You will have %d sections of length %f with no remainder. Congratulations on the perfect division.\n', N, param);
                    end
                end

                % If numsec is true, validate param and divide linelen into param sections
                if numsec
                    if param <= 1
                        error('Number of sections must be greater than 1. Come on, this is basic stuff.');
                    end

                    % Calculate the length of each section
                    section_length = linelen / param;
                    fprintf('You will have %d sections of length %f.\n', param, section_length);
                    N=param;
                    remainder=0;
                end
            catch ME
                % Error handling - time to tell the user how much they failed
                fprintf('Error: %s\n', ME.message);

                % Check if the error is related to invalid XML file
                if contains(ME.message, 'Failed to read XML')
                    msg = 'You absolute muppet, this isn''t even a valid XML file. Try again with an actual XML file.';
                    f = uimsgbox(app,msg, 'You lose, fella.','error');
                    return;
                elseif contains(ME.message, 'must be a string scalar or character vector')
                    msg = 'Got a file, alsjeblieft?';
                    f = uimsgbox(app,msg, 'You lose, fella.','error');
                    return;
                else
                    % Generic error message for other potential issues
                    msg = ME.message;
                    f = uimsgbox(app,msg, 'You lose, fella.','error');
                    return;
                end
            end

            disp('It seems we have a suitable victim. Prepare for rip and tear...');


            [filepath,name,ext] = fileparts(myfile)
            %output file name
            outfile=fullfile(filepath,strcat(name,'_Ripped',ext));

            %initialize the output struct
            outstruct=LCCData;
            TopLeftX=200; % 200 + 240 LCCs per row give the optimal layout
            TopLeftY=0; %will need this later to place the LCCs
            outstruct.header.TopLeftXAttribute=TopLeftX;
            outstruct.header.TopLeftYAttribute=TopLeftY;
            numphases=LCCData.objects.comp.comp_content.NumPhasesAttribute;


            % now prepare for the damn components section
            offsetX=80;
            offsetY=numphases*10 + 40;
            LCC_per_row=20; %240;
            rowcount=1; %counts number of times that LCC_per_row is reached
            colcount=1; % will be reset to 1 every LCC_per_row
            conn_idx=1;

            acclen=0; %just for information purposes

            for i=1:N
                thislen=section_length;
                if i==N
                    thislen = thislen + remainder;
                end
                if isfield(LCCData.objects.comp.LCC,'line_header')
                    thislen=thislen/1000; %WHY FUCKING OVERHEAD LINES USE KM???? WHY GOEDEN WHY???
                end

                acclen= acclen+thislen;
                outstruct.objects.comp(i) = outstruct.objects.comp(1);
                outstruct.objects.comp(i).IdAttribute=string(['LCCSEC' num2str(i)]);
                outstruct.objects.comp(i).CapanglAttribute=90;
                outstruct.objects.comp(i).CapPosXAttribute=-10;
                outstruct.objects.comp(i).CapPosYAttribute=-25;
                outstruct.objects.comp(i).comp_content.PosXAttribute=TopLeftX+(colcount*offsetX);
                outstruct.objects.comp(i).comp_content.PosYAttribute=TopLeftY+(rowcount*offsetY);
                colcount=colcount+1;
                thiscaption='';
                outstruct.objects.comp(i).CaptionAttribute=thiscaption;
                outstruct.objects.comp(i).comp_content.SinglePhaseIconAttribute='true';

                for j=1:numphases
                    outstruct.objects.conn(conn_idx).conn_content.NumPhasesAttribute=1;
                    outstruct.objects.conn(conn_idx).conn_content.Pos1XAttribute=TopLeftX+((colcount-1)*offsetX)+20;
                    outstruct.objects.conn(conn_idx).conn_content.Pos1YAttribute=TopLeftY+(rowcount*offsetY)-20+j*10;
                    outstruct.objects.conn(conn_idx).conn_content.Pos2XAttribute=TopLeftX+((colcount-1)*offsetX)+offsetX-20;
                    outstruct.objects.conn(conn_idx).conn_content.Pos2YAttribute=TopLeftY+(rowcount*offsetY)-20+j*10;
                    thiscolor='0'; %dont know what to do, paint it black
                    outstruct.objects.conn(conn_idx).conn_content.ColorAttribute=thiscolor;
                    conn_idx=conn_idx+1;
                end


                if mod(i,LCC_per_row)==0
                    colcount=1;
                    rowcount=rowcount+1;
                end

                %EACH ONE OF THESE BIG BOYS NEEDS Nph NODES
                y0=-20;
                for k=1:numphases %input nodes
                    y0=y0+10;
                    outstruct.objects.comp(i).comp_content.node(k).NameAttribute=string(sprintf('IN%d',k));
                    outstruct.objects.comp(i).comp_content.node(k).ValueAttribute=string([sprintf('%02d',k) sprintf('%04d',i)]);
                    outstruct.objects.comp(i).comp_content.node(k).UserNamedAttribute='true';
                    % outstruct.objects.comp(i).comp_content.node(k).NumPhasesAttribute=1;
                    outstruct.objects.comp(i).comp_content.node(k).KindAttribute=k;
                    outstruct.objects.comp(i).comp_content.node(k).PosXAttribute=-20;
                    outstruct.objects.comp(i).comp_content.node(k).PosYAttribute=y0;
                    outstruct.objects.comp(i).comp_content.node(k).NamePosXAttribute=0;
                    outstruct.objects.comp(i).comp_content.node(k).NamePosYAttribute=0;
                end

                y0=-20;
                for k=numphases+1:2*numphases %output nodes
                    w=k-numphases;
                    y0=y0+10;
                    outstruct.objects.comp(i).comp_content.node(k).NameAttribute=string(sprintf('OUT%d',w));
                    outstruct.objects.comp(i).comp_content.node(k).ValueAttribute=string([sprintf('%02d',w) sprintf('%04d',i+1)]);
                    outstruct.objects.comp(i).comp_content.node(k).UserNamedAttribute='true';
                    % outstruct.objects.comp(i).comp_content.node(k).NumPhasesAttribute=1;
                    outstruct.objects.comp(i).comp_content.node(k).KindAttribute=w;
                    outstruct.objects.comp(i).comp_content.node(k).PosXAttribute=20;
                    outstruct.objects.comp(i).comp_content.node(k).PosYAttribute=y0;
                    outstruct.objects.comp(i).comp_content.node(k).NamePosXAttribute=0;
                    outstruct.objects.comp(i).comp_content.node(k).NamePosYAttribute=0;
                end

                % yeah this fucking sucks and probably can be done better
                % LCCData.objects.comp.comp_content.data(1) -< lenght
                outstruct.objects.comp(i).comp_content.data(1).NameAttribute='Length';
                outstruct.objects.comp(i).comp_content.data(1).ValueAttribute=thislen; %yep, it's here
            end
            
            i=1;
            outstruct.objects.text(i).text_content.PosXAttribute=TopLeftX;
            outstruct.objects.text(i).text_content.PosYAttribute=TopLeftY+10;
            outstruct.objects.text(i).text_content.FontNameAttribute='Microsoft Sans Serif';
            outstruct.objects.text(i).text_content.FontSizeAttribute=10;
            outstruct.objects.text(i).text_content.FontStyleAttribute=0;
            outstruct.objects.text(i).text_content.ColorAttribute=255;
            outstruct.objects.text(i).text_content.OrientationAttribute=0;
            outstruct.objects.text(i).text_content.BckColAttribute=16777215;
            outstruct.objects.text(i).text_content.FrmColAttribute=16777215;
            outstruct.objects.text(i).text_content.Text=['One more circuit file brutally victimized by LCC Ripper.'];

            i=i+1;
            outstruct.objects.text(i).text_content.PosXAttribute=TopLeftX;
            outstruct.objects.text(i).text_content.PosYAttribute=TopLeftY+40;
            outstruct.objects.text(i).text_content.FontNameAttribute='Microsoft Sans Serif';
            outstruct.objects.text(i).text_content.FontSizeAttribute=10;
            outstruct.objects.text(i).text_content.FontStyleAttribute=2;
            outstruct.objects.text(i).text_content.ColorAttribute=16711680;
            outstruct.objects.text(i).text_content.OrientationAttribute=0;
            outstruct.objects.text(i).text_content.BckColAttribute=16777215;
            outstruct.objects.text(i).text_content.FrmColAttribute=16777215;
            outstruct.objects.text(i).text_content.Text=['github.com/amaurigmartins/LCCRipper'];

            %output to XML and fix non-conforming tags
            writestruct(outstruct, outfile,'StructNodeName','project');
            func_fix_text_labels(app,outfile, outfile)
            func_fix_var_tags(app,outfile, outfile)

            msg = sprintf('Sliced file successfully written to ''%s''.',outfile);
            f = uimsgbox(app,msg, 'Rip and tear until it''s done!','success');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LCCRipperUIFigure = uifigure('Visible', 'off');
            app.LCCRipperUIFigure.Name = 'LCC Ripper 🔪';
            app.LoadFileButton = uibutton(app.LCCRipperUIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = '📂';
            app.SourcefilexlmLabel = uilabel(app.LCCRipperUIFigure);
            app.SourcefilexlmLabel.Text = 'Source file [.xml]:';
            app.SourcefileatpEditField = uitextarea(app.LCCRipperUIFigure);
            app.SourcefileatpEditField.Editable = 'off';
            app.SlicendiceButton = uibutton(app.LCCRipperUIFigure, 'push');
            app.SlicendiceButton.ButtonPushedFcn = createCallbackFcn(app, @SlicendiceButtonPushed, true);
            app.SlicendiceButton.Text = ' Slice ''n'' dice';
            app.LinelengthmEditFieldLabel = uilabel(app.LCCRipperUIFigure);
            app.LinelengthmEditFieldLabel.Text = 'Line length [m]';
            app.LinelengthmEditField = uieditfield(app.LCCRipperUIFigure, 'numeric');
            app.LinelengthmEditField.Limits = [0 Inf];
            app.SlicemethodButtonGroup = uibuttongroup(app.LCCRipperUIFigure);
            app.SlicemethodButtonGroup.Title = 'Slice method';
            app.NumberofsectionsButton = uiradiobutton(app.SlicemethodButtonGroup);
            app.NumberofsectionsButton.Text = 'Number of sections';
            app.NumberofsectionsButton.Value = true;
            app.SpanlengthButton = uiradiobutton(app.SlicemethodButtonGroup);
            app.SpanlengthButton.Text = 'Span length';
            app.ValueEditFieldLabel = uilabel(app.SlicemethodButtonGroup);
            app.ValueEditFieldLabel.Text = 'Value';
            app.ValueEditField = uieditfield(app.SlicemethodButtonGroup, 'numeric');
            app.ValueEditField.Limits = [0 Inf];
            % Show the figure after all components are created
            app.LCCRipperUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lccripper

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LCCRipperUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LCCRipperUIFigure)
        end
    end
end